classdef dielec_tool_d0_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        summationfactorButtonGroup    matlab.ui.container.ButtonGroup
        Button                        matlab.ui.control.RadioButton
        wowButton                     matlab.ui.control.RadioButton
        q2Button                      matlab.ui.control.RadioButton
        q4Button                      matlab.ui.control.RadioButton
        incgammaCheckBox              matlab.ui.control.CheckBox
        wv2q2Button                   matlab.ui.control.RadioButton
        q2Button_2                    matlab.ui.control.RadioButton
        updateButton                  matlab.ui.control.Button
        FoldernameEditFieldLabel      matlab.ui.control.Label
        FoldernameEditField           matlab.ui.control.EditField
        q_selectionDropDownLabel      matlab.ui.control.Label
        q_selectionDropDown           matlab.ui.control.DropDown
        savePDFCheckBox               matlab.ui.control.CheckBox
        saveplotdataCheckBox          matlab.ui.control.CheckBox
        NameEditFieldLabel            matlab.ui.control.Label
        NameEditField                 matlab.ui.control.EditField
        yMinEditFieldLabel            matlab.ui.control.Label
        yMinEditField                 matlab.ui.control.NumericEditField
        yMaxEditFieldLabel            matlab.ui.control.Label
        yMaxEditField                 matlab.ui.control.NumericEditField
        typeDropDownLabel             matlab.ui.control.Label
        typeDropDown                  matlab.ui.control.DropDown
        pointsizeEditFieldLabel       matlab.ui.control.Label
        pointsizeEditField            matlab.ui.control.NumericEditField
        xMinEditFieldLabel            matlab.ui.control.Label
        xMinEditField                 matlab.ui.control.NumericEditField
        xMaxEditFieldLabel            matlab.ui.control.Label
        xMaxEditField                 matlab.ui.control.NumericEditField
        q_maxSliderLabel              matlab.ui.control.Label
        q_maxSlider                   matlab.ui.control.Slider
        holdCheckBox                  matlab.ui.control.CheckBox
        autoscaleButton               matlab.ui.control.StateButton
        UIAxes_2                      matlab.ui.control.UIAxes
        q_maxEditFieldLabel           matlab.ui.control.Label
        q_maxEditField                matlab.ui.control.NumericEditField
        numButton                     matlab.ui.control.StateButton
        E_kinkeVEditFieldLabel        matlab.ui.control.Label
        E_kinkeVEditField             matlab.ui.control.NumericEditField
        grid_xEditFieldLabel          matlab.ui.control.Label
        grid_xEditField               matlab.ui.control.NumericEditField
        grid_yEditFieldLabel          matlab.ui.control.Label
        grid_yEditField               matlab.ui.control.NumericEditField
        q_cutEditFieldLabel           matlab.ui.control.Label
        q_cutEditField                matlab.ui.control.NumericEditField
        defaultButton                 matlab.ui.control.StateButton
        AdditionalKpointSetaroundgammaPanel  matlab.ui.container.Panel
        useadditionalkptssetCheckBox  matlab.ui.control.CheckBox
        grid_yEditField_2Label        matlab.ui.control.Label
        grid_yEditField_2             matlab.ui.control.NumericEditField
        grid_xEditField_2Label        matlab.ui.control.Label
        grid_xEditField_2             matlab.ui.control.NumericEditField
        q_limEditFieldLabel           matlab.ui.control.Label
        q_limEditField                matlab.ui.control.NumericEditField
        FoldernameEditField_2Label    matlab.ui.control.Label
        FoldernameEditField_2         matlab.ui.control.EditField
        vacuumEditField_2Label        matlab.ui.control.Label
        vacuumEditField_2             matlab.ui.control.NumericEditField
        onlyreplaceCheckBox           matlab.ui.control.CheckBox
        vacuumEditFieldLabel          matlab.ui.control.Label
        vacuumEditField               matlab.ui.control.NumericEditField
        qptstoolsPanel                matlab.ui.container.Panel
        createButton                  matlab.ui.control.Button
        q_maxEditField_2Label         matlab.ui.control.Label
        q_maxEditField_2              matlab.ui.control.NumericEditField
        UIAxes_3                      matlab.ui.control.UIAxes
        originalEditFieldLabel        matlab.ui.control.Label
        originalEditField             matlab.ui.control.NumericEditField
        subsetEditFieldLabel          matlab.ui.control.Label
        subsetEditField               matlab.ui.control.NumericEditField
        ExperimentalDataPanel         matlab.ui.container.Panel
        offsetEditFieldLabel          matlab.ui.control.Label
        offsetEditField               matlab.ui.control.NumericEditField
        experimtaldataCheckBox        matlab.ui.control.CheckBox
        scaleEditFieldLabel           matlab.ui.control.Label
        scaleEditField                matlab.ui.control.NumericEditField
        shiftEditFieldLabel           matlab.ui.control.Label
        shiftEditField                matlab.ui.control.NumericEditField
        q_minSliderLabel              matlab.ui.control.Label
        q_minSlider                   matlab.ui.control.Slider
        vacuum_gammaEditFieldLabel    matlab.ui.control.Label
        vacuum_gammaEditField         matlab.ui.control.NumericEditField
        d_0EditFieldLabel             matlab.ui.control.Label
        d_0EditField                  matlab.ui.control.NumericEditField
        g_perpCheckBox                matlab.ui.control.CheckBox
        g_parallelCheckBox            matlab.ui.control.CheckBox
    end

    methods (Access = public)
        
    end
    

    methods (Access = private)

        % Callback function: experimtaldataCheckBox, 
        % incgammaCheckBox, q_maxEditField, q_maxSlider, 
        % q_selectionDropDown, scaleEditField, shiftEditField, 
        % summationfactorButtonGroup, typeDropDown, updateButton, 
        % xMaxEditField, xMinEditField, yMaxEditField, yMinEditField
        function updateButtonPushed(app, event)
            if app.holdCheckBox.Value
                hold(app.UIAxes,'on')
            else
                app.UIAxes.cla; %clear graph
                close all
            end
            if app.FoldernameEditField.Value=="" %fallback when no input file is given
                app.FoldernameEditField.Value='output_dir';
            end
            filename_qpts=strcat(app.FoldernameEditField.Value,'\qpts');
            filename_dielec=strcat(app.FoldernameEditField.Value,'\dielecR_');
            %------------------bravai matrix---------------------------
            a_mat =h5read(strcat(app.FoldernameEditField.Value,'\basis.hdf'),'/cell/amat');
            b_mat =h5read(strcat(app.FoldernameEditField.Value,'\basis.hdf'),'/cell/bmat');
            %------------------reading qpts ---------------------------
            dA=norm(cross(b_mat(1,:),b_mat(2,:)))/(app.grid_xEditField.Value*app.grid_yEditField.Value);
            q_c=(dA/3.14)^(1/2);
            fid = fopen(filename_qpts,'r');
            data_qpts_raw=textscan(fid,'%f%f%f%f', 'CommentStyle','#');
            fclose(fid);
            data_qpts=cell2mat(data_qpts_raw);
            num_kpts=data_qpts(1,1);
            factor_kpts=data_qpts(1,2);
            for i=1:num_kpts
                q_array(i,1:3)=b_mat'*(data_qpts(i+1,1:3)/factor_kpts)';
                q_array(i,4)=q_array(i,1:3)*q_array(i,1:3)';  %q^2 for later use (internal coordinates)(kartesian?)
                q_array(i,5)=data_qpts(i+1,4);
                q_array(i,6)=dA;
                q_array(i,7)=app.vacuumEditField.Value;
            end
            %----------------- reading dielec -------------------------
            for i=1:num_kpts
                if isfile(strcat(filename_dielec,'001'))
                    fid = fopen(strcat(filename_dielec,num2str(i,'%03d')),'r');
                else
                    fid = fopen(strcat(filename_dielec,num2str(i,'%04d')),'r');
                end
                data_dielec_raw=textscan(fid,'%f%f%f', 'CommentStyle','#');
                fclose(fid);
                data_dielec=cell2mat(data_dielec_raw);
                if(i==1)
                    [energy_mesh,reorder]=sort(data_dielec(:,1)*27.2); %reorder saves the swapping of places of entries
                end
                dielec_full(i,:,1)=data_dielec(reorder,2); %real part
                dielec_full(i,:,2)=data_dielec(reorder,3); %imag part
            end
            %-------------------reading second dataset-------------------
            if app.useadditionalkptssetCheckBox.Value
                filename_qpts_2=strcat(app.FoldernameEditField_2.Value,'\qpts');
                filename_dielec_2=strcat(app.FoldernameEditField_2.Value,'\dielecR_');
                %------------------reading qpts ---------------------------
                dA_2=norm(cross(b_mat(1,:),b_mat(2,:)))/(app.grid_xEditField_2.Value*app.grid_yEditField_2.Value);
                q_c=(dA_2/3.14)^(1/2);
                fid= fopen(filename_qpts_2,'r');
                data_qpts_raw=textscan(fid,'%f%f%f%f', 'CommentStyle','#');
                fclose(fid);
                data_qpts=cell2mat(data_qpts_raw);
                num_kpts_2=data_qpts(1,1);
                factor_kpts=data_qpts(1,2);
                for i=1:num_kpts_2
                    q_array_2(i,1:3)=b_mat'*(data_qpts(i+1,1:3)/factor_kpts)';
                    q_array_2(i,4)=q_array_2(i,1:3)*q_array_2(i,1:3)';  %q^2 for later use (internal coordinates)
                    q_array_2(i,5)=data_qpts(i+1,4);
                    q_array_2(i,6)=dA_2;
                    q_array_2(i,7)=app.vacuumEditField_2.Value;
                end
                if (app.q_limEditField.Value==0) %fallback when no input file is given
                    app.q_limEditField.Value=(max(q_array_2(:,4)))^(1/2);
                end
                %----------------- reading dielec -------------------------
                vac_scaling=app.vacuumEditField_2.Value/app.vacuumEditField.Value;
                for i=1:num_kpts_2
                    if isfile(strcat(filename_dielec_2,'001'))
                        fid = fopen(strcat(filename_dielec_2,num2str(i,'%03d')),'r');
                    else
                        fid = fopen(strcat(filename_dielec_2,num2str(i,'%04d')),'r');
                    end
                    data_dielec_raw=textscan(fid,'%f%f%f', 'CommentStyle','#');
                    fclose(fid);
                    data_dielec=cell2mat(data_dielec_raw);
                if(i==1)
                    [energy_mesh,reorder]=sort(data_dielec(:,1)); %reorder saves the swapping of places of entries
                end
                    if (app.typeDropDown.Value=='3') %EELS
                        dielec_full_2(i,:,1)=data_dielec(reorder,2)/vac_scaling; %real part
                        dielec_full_2(i,:,2)=data_dielec(reorder,3)/vac_scaling; %imag part
                    elseif (app.typeDropDown.Value=='1') %imag(eps)
                        dielec_full_2(i,:,1)=data_dielec(reorder,2)*vac_scaling; %real part
                        dielec_full_2(i,:,2)=data_dielec(reorder,3)*vac_scaling; %imag part
                    else
                        dielec_full_2(i,:,1)=data_dielec(reorder,2); %real part
                        dielec_full_2(i,:,2)=data_dielec(reorder,3); %imag part
                    end
                end
                %create_new_qpts_and_dielec
                q_array_1=q_array;
                dielec_full_1=dielec_full;
                %q_array=[];
                %dielec_full=[];
                j=0;
                if app.onlyreplaceCheckBox.Value
                    for k=1:num_kpts
                        %dielec_full(k,:,:)=dielec_full_1(k,:,:);
                        for l=1:num_kpts_2
                            if (q_array_1(k,1)==q_array_2(l,1))
                                if (q_array_1(k,2)==q_array_2(l,2))
                                    if (q_array_1(k,3)==q_array_2(l,3))
                                        dielec_full(k,:,:)=dielec_full_2(l,:,:);
                                        q_array(k,:)=q_array_2(l,:);
                                    end
                                end
                            end
                        end
                    end
                    %q_array=q_array_1;
                else
                    for i=1:num_kpts_2
                        if (q_array_2(i,4)<=app.q_limEditField.Value^2)
                            j=j+1;
                            q_array(j,:)=q_array_2(i,:);
                            dielec_full(j,:,:)=dielec_full_2(i,:,:);
                        end
                    end
                    for i=1:num_kpts
                        if (q_array_1(i,4)>app.q_limEditField.Value^2)
                            j=j+1;
                            q_array(j,:)=q_array_1(i,:);
                            dielec_full(j,:,:)=dielec_full_1(i,:,:);
                        end
                    end
                end
                num_kpts=size(q_array,1);
            end
            %---------------------dielec plot type----------------------
            if (app.typeDropDown.Value=='3') %EELS
                for i=1:size(dielec_full,1)
                    for j=1:size(dielec_full,2)
                        dielec(i,j)=dielec_full(i,j,2)/(dielec_full(i,j,1)^2+dielec_full(i,j,2)^2);
                    end
                end
            elseif (app.typeDropDown.Value=='2') %real(eps)
                dielec(:,:)=dielec_full(:,:,1);
            elseif (app.typeDropDown.Value=='4') %mixed EELS and imag(eps)
                method_mix=0;
                if method_mix==1
                    for i=1:size(dielec_full,1)
                        e=2.7182818284590452353602874;
                        factor_imag=e^(-sqrt(q_array(i,4))*10);
                        factor_EELS=1-factor_imag;
                        for j=1:size(dielec_full,2)
                            dielec(i,j)=factor_EELS*(dielec_full(i,j,2)/(dielec_full(i,j,1)^2+dielec_full(i,j,2)^2))+factor_imag*(dielec_full(i,j,2));
                        end
                    end
                elseif method_mix==2
                    for i=1:size(dielec_full,1)
                        e=2.7182818284590452353602874;
                        factor_imag=e^(-sqrt(q_array(i,4))*20);
                        for j=1:size(dielec_full,2)
                            y=1/(dielec_full(i,j,1)+dielec_full(i,j,2)*sqrt(-1))-1;
                            dielec(i,j)=imag(-1-y+(factor_imag*y)^2/(1+factor_imag*y));
                        end
                    end
                else
                    for i=1:size(dielec_full,1)
                        const_bohr=5.29177210903*10^(-11);
                        L=q_array(i,7)*10^(-10)/const_bohr; %q_array(i,7) is the vacuum (angstrom)
                        e=2.7182818284590452353602874;
                        for j=1:size(dielec_full,2)
                            y=1/(dielec_full(i,j,1)+dielec_full(i,j,2)*sqrt(-1))-1;
                            dielec(i,j)=-imag(q_array(i,7)/(1/y+(sqrt(q_array(i,4))*L)/(e^(sqrt(q_array(i,4))*L)-1)));
                        end
                    end
                end
            else %imag(eps)
                dielec(:,:)=dielec_full(:,:,2);
            end
            
            
            %--------------momentum selection-------------------------------
            
            momentum_list(1)={'all'};
            momentum_numlist=1:size(q_array,1);
            momentum_numlist=squeeze(momentum_numlist);
            for i=1:(size(momentum_numlist,2))
                momentum_list(i+1)={strcat(num2str(q_array(i,1)),'; ',num2str(q_array(i,2)),'; ',num2str(q_array(i,3)))};
            end
            momentum_numlist=[0,momentum_numlist];
            app.q_selectionDropDown.Items = momentum_list;
            app.q_selectionDropDown.ItemsData = momentum_numlist;
            momentum=app.q_selectionDropDown.Value;          %0 means all
            
            %---------------------output name default----------------
            if app.NameEditField.Value==""
                app.NameEditField.Value='EELS_spectrum';
            end
            %---------------------selection and summation--------------
            app.q_maxSlider.Limits=[(min(q_array(:,4)))^(1/2),(max(q_array(:,4)))^(1/2)];
            if app.numButton.Value
                app.q_maxSlider.Value=app.q_maxEditField.Value;
            else
                app.q_maxEditField.Value=app.q_maxSlider.Value;
            end
            dielec_plot=zeros([1,size(dielec,2)]);
            %prefactor_plot=zeros([1,size(dielec,2)]);
            const_J_to_eV=1/(1.60218*10^(-19));
            const_bohr=5.29177210903*10^(-11);
            const_m_e=9.10938356*10^(-31); %in kg
            const_c=299792458 ;
            const_hbar_eV=6.582119569*10^(-16);
            const_hbar=6.582119569*10^(-16);
            E_kin=app.E_kinkeVEditField.Value*10^3/const_J_to_eV
            v_bohr=const_c*(1-((const_m_e*const_c^2/(E_kin+const_m_e*const_c^2))^2))^(1/2)/const_bohr;
            v=const_c*(1-((const_m_e*const_c^2/(E_kin+const_m_e*const_c^2))^2))^(1/2)
            %dA=norm(cross(b_mat(1,:),b_mat(2,:)))/(app.grid_xEditField.Value*app.grid_yEditField.Value);
            %q_c=norm(b_mat(1,:))/app.grid_xEditField.Value/4;
            %q_c=(dA/3.14)^(1/2)
            if app.defaultButton.Value
                app.q_cutEditField.Value=q_c;
            else
                q_c=app.q_cutEditField.Value;
            end
            if (momentum==0)
                for i=2:size(dielec,1)
                    if (q_array(i,4)<=app.q_maxSlider.Value^2)
                        if app.Button.Value
                            dielec_plot=dielec_plot+dielec(i,:)*q_array(i,5);
                        elseif app.wowButton.Value
                            dielec_plot=dielec_plot+dielec(i,:);
                        elseif app.q2Button.Value
                            dielec_plot=dielec_plot+dielec(i,:)*q_array(i,5)/q_array(i,4);
                            % prefactor_plot=1/q_array(i,4);
                        elseif app.q2Button_2.Value
                            dielec_plot=dielec_plot+dielec(i,:)*q_array(i,5)*q_array(i,4);
                        elseif app.q4Button.Value
                            dielec_plot=dielec_plot+dielec(i,:)*q_array(i,5)/(q_array(i,4)^2);
                        elseif app.wv2q2Button.Value
                            for j=1:size(energy_mesh,1)
                                w=energy_mesh(j)/const_hbar_eV;
                                dielec_plot(j)=dielec_plot(j)+dielec(i,j)*q_array(i,6)*q_array(i,5)/(1^2*((w/v_bohr)^2+q_array(i,4)));
                                % prefactor_plot(j)=prefactor_plot(j)+q_array(i,5)*1/(v^2*((w/v)^2+q_array(i,4)));
                            end
                            %k_z=((const_m_e/const_hbar*(1/v^2+1/const_c^2)^(-1/2))/const_bohr)^2
                            %dielec_plot=dielec_plot+dielec(i,:)*q_array(i,5)/(v^2*(((const_m_e/const_hbar*(1/v^2+1/const_c^2)^(-1/2))/const_bohr)^2+q_array(i,4)));
                        else
                            dielec_plot=sum(dielec(2:end,:),1);
                        end
                    end
                end
                if (app.typeDropDown.Value=='4') %mixed EELS and imag(eps)
                    dielec_plot=dielec_plot;
                else
                    dielec_plot=dielec_plot*app.vacuumEditField.Value;
                end
                
                if app.incgammaCheckBox.Value
                    %dielec_plot=dielec_plot+dielec(1,:);
                    fid = fopen(strcat(filename_dielec,'xy'),'r');
                    data_dielec_raw=textscan(fid,'%f%f%f', 'CommentStyle','#');
                    fclose(fid);
                    data_dielec_xy=cell2mat(data_dielec_raw);
                    [data_dielec_xy(:,1),reorder2]=sort(data_dielec_xy(:,1));
                    %data_dielec_xy(:,2:3)=data_dielec_xy(:,2:3)*3;
                    fid = fopen(strcat(filename_dielec,'z'),'r');
                    data_dielec_raw=textscan(fid,'%f%f%f', 'CommentStyle','#');
                    fclose(fid);
                    data_dielec_z=cell2mat(data_dielec_raw);
                    [data_dielec_z(:,1),reorder3]=sort(data_dielec_z(:,1));
                    
                    % if (app.typeDropDown.Value=='3') %EELS
                    %
                    % elseif (app.typeDropDown.Value=='2') %real(eps)
                    %     dielec_xy(:)=data_dielec_xy(:,2);
                    %     dielec_z(:)=data_dielec_z(:,2);
                    % else %imag(eps)
                    dielec_xy(:)=data_dielec_xy(reorder2,3);
                    data_dielec_xy(:,3)=data_dielec_xy(reorder2,3);
                    dielec_z(:)=data_dielec_z(reorder3,3);
                    data_dielec_z(reorder3,3)=data_dielec_z(reorder3,3);
                    % end
                    for j=1:size(energy_mesh,1)
                        if (app.g_perpCheckBox.Value)
                            dielec_z(j)=-data_dielec_z(j,3)/(data_dielec_z(j,2)^2+data_dielec_z(j,3)^2);
                        end
                        if (app.g_parallelCheckBox.Value)
                            dielec_xy(j)=-data_dielec_xy(j,3)/(data_dielec_xy(j,2)^2+data_dielec_xy(j,3)^2);
                        end 
                        w=energy_mesh(j)/const_hbar_eV;
                        x=w/(q_c*v_bohr)
                        dielec_plot(j)=dielec_plot(j)+app.vacuum_gammaEditField.Value*3.14/(1^2)*(dielec_xy(j)*(log((x^2+1)/(x^2))-(1/(x^2+1)))+dielec_z(j)*(1/(x^2+1)));
                    end
                end
            else
                dielec_plot=dielec(momentum,:)*app.vacuumEditField.Value;
            end
            %---------------------plotting q points --------------------
            app.UIAxes_2.cla; %clear graph
            hold(app.UIAxes_2,'on')
            axis(app.UIAxes_2,[-max(q_array(:,4))^(1/2),max(q_array(:,4))^(1/2),-max(q_array(:,4))^(1/2),max(q_array(:,4))^(1/2)])
            color_set=[0.8,0.8,0.8];
            if (app.q_maxSlider.Value==0)
                q_max=0.001;
            else
                q_max=app.q_maxSlider.Value;
            end
            scatter(app.UIAxes_2,0,0,q_max/0.6*9000,color_set,'fill')
            color_set=[0.8,0.2,0];
            scatter(app.UIAxes_2,q_array(:,1),q_array(:,2),5,color_set,'fill')
            if app.onlyreplaceCheckBox.Value && app.useadditionalkptssetCheckBox.Value
                color_set=[0.2,0.8,0];
                scatter(app.UIAxes_2,q_array_2(:,1),q_array_2(:,2),5,color_set,'fill')
            end
            hold(app.UIAxes_2,'off')
            %---------------------setting graph properties---------------
            ylabel(app.UIAxes,'EELS') % y-axis label
            xlabel(app.UIAxes,'Energy (eV)')    % x-axis label
            dielec_plot=dielec_plot/app.d_0EditField.Value;
            %app.UIAxes.XTick = kpts_dist(special_points_pos);
            %app.UIAxes.GridColor = [0.15 0.15 0.15];
            if app.autoscaleButton.Value
                if (momentum==0)
                    app.xMaxEditField.Value=max(energy_mesh);
                    app.yMinEditField.Value=min(dielec_plot);
                    app.yMaxEditField.Value=max(dielec_plot);
                    app.xMinEditField.Value=min(energy_mesh);
                else
                    app.xMaxEditField.Value=max(energy_mesh);
                    app.yMinEditField.Value=min(min(dielec));
                    app.yMaxEditField.Value=max(max(dielec));
                    app.xMinEditField.Value=min(energy_mesh);
                end
            end
            axis(app.UIAxes,[app.xMinEditField.Value,app.xMaxEditField.Value,app.yMinEditField.Value,app.yMaxEditField.Value])
            %color_set=[0.4,0.7,1]; %CMY
            size_dot=app.pointsizeEditField.Value;
            %----------------------plotting-----------------------------
            % figure
            % plot(energy_mesh(:),prefactor_plot(:)*10^30)
            
            plot(app.UIAxes,energy_mesh(:),dielec_plot(:))
            %----------------exp comparison ---------------------------
            if app.experimtaldataCheckBox.Value
                hold(app.UIAxes,'on')
                fid = fopen('exp_data.txt','r');
                exp_data_raw=textscan(fid,'%f%f', 'CommentStyle','#');
                fclose(fid);
                exp_data=cell2mat(exp_data_raw);
                plot(app.UIAxes,exp_data(:,1)+app.shiftEditField.Value,(exp_data(:,2)-app.offsetEditField.Value)*app.scaleEditField.Value)
                hold(app.UIAxes,'off')
            end
            if app.savePDFCheckBox.Value
                saving_pdf
                matlabfrag(app.NameEditField.Value);
                mlf2pdf(99,app.NameEditField.Value);
                matlabfrag(strcat(app.NameEditField.Value,'_bz'));
                mlf2pdf(98,strcat(app.NameEditField.Value,'_bz'));
            end
            if app.saveplotdataCheckBox.Value
                saving_txt
            end
        end

        % Button pushed function: createButton
        function createButtonPushed(app, event)
            filename_qpts=strcat(app.FoldernameEditField.Value,'\qpts');
            %------------------bravai matrix---------------------------
            a_mat =h5read(strcat(app.FoldernameEditField.Value,'\basis.hdf'),'/cell/amat');
            b_mat =h5read(strcat(app.FoldernameEditField.Value,'\basis.hdf'),'/cell/bmat');
            %------------------reading qpts ---------------------------
            dA=norm(cross(b_mat(1,:),b_mat(2,:)))/(app.grid_xEditField_2.Value*app.grid_yEditField.Value);
            fid = fopen(filename_qpts,'r');
            data_qpts_raw=textscan(fid,'%f%f%f%f', 'CommentStyle','#');
            fclose(fid);
            data_qpts=cell2mat(data_qpts_raw);
            num_kpts=data_qpts(1,1);
            factor_kpts=data_qpts(1,2);
            for i=1:num_kpts
                q_array(i,1:3)=b_mat'*(data_qpts(i+1,1:3)/factor_kpts)';
                q_array(i,4)=q_array(i,1:3)*q_array(i,1:3)';  %q^2 for later use (internal coordinates)
                q_array(i,5)=data_qpts(i+1,4);
                q_array(i,6)=dA;
            end
            j=0;
            for i=1:num_kpts
                if (q_array(i,4)<=app.q_maxEditField_2.Value^2)
                    j=j+1;
                    q_array_sub(j,:)=q_array(i,:);
                    q_array_sub_write(j,1:3)=data_qpts(i+1,1:3);
                    q_array_sub_write(j,4)=data_qpts(i+1,4);
                end
            end
            %---------------------plotting q points --------------------
            app.UIAxes_3.cla; %clear graph
            hold(app.UIAxes_3,'on')
            axis(app.UIAxes_3,[0,max(q_array(:,4))^(1/2),0,max(q_array(:,4))^(1/2)])
            color_set=[0.8,0.2,0];
            scatter(app.UIAxes_3,q_array(:,1),q_array(:,2),5,color_set,'fill')
            color_set=[0.2,0.8,0];
            scatter(app.UIAxes_3,q_array_sub(:,1),q_array_sub(:,2),5,color_set,'fill')
            hold(app.UIAxes_2,'off')
            %---------------------writing out qpts---------------
            num_kpts_sub=size(q_array_sub,1);
            app.originalEditField.Value=num_kpts;
            app.subsetEditField.Value=num_kpts_sub;
            dlmwrite('qpts_subset',[num_kpts_sub,factor_kpts],'delimiter','\t')
            dlmwrite('qpts_subset',q_array_sub_write,'-append','delimiter','\t') 
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EELS')
            app.summationfactorButtonGroup = uibuttongroup(app.UIFigure);
            app.summationfactorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.summationfactorButtonGroup.Tooltip = {'prefactor for summation of the different spectra'};
            app.summationfactorButtonGroup.Title = 'summation factor';
            app.Button = uiradiobutton(app.summationfactorButtonGroup);
            app.Button.Text = '1';
            app.wowButton = uiradiobutton(app.summationfactorButtonGroup);
            app.wowButton.Text = '1 w/o w';
            app.q2Button = uiradiobutton(app.summationfactorButtonGroup);
            app.q2Button.Text = '1/q^2';
            app.q4Button = uiradiobutton(app.summationfactorButtonGroup);
            app.q4Button.Text = '1/q^4';
            app.incgammaCheckBox = uicheckbox(app.summationfactorButtonGroup);
            app.incgammaCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.incgammaCheckBox.Text = 'inc. gamma';
            app.incgammaCheckBox.Value = true;
            app.wv2q2Button = uiradiobutton(app.summationfactorButtonGroup);
            app.wv2q2Button.Text = '1/((w/v)^2+q^2)';
            app.wv2q2Button.Value = true;
            app.q2Button_2 = uiradiobutton(app.summationfactorButtonGroup);
            app.q2Button_2.Text = 'q^2';
            app.updateButton = uibutton(app.UIFigure, 'push');
            app.updateButton.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.updateButton.Text = 'update';
            app.FoldernameEditFieldLabel = uilabel(app.UIFigure);
            app.FoldernameEditFieldLabel.Text = 'Foldername';
            app.FoldernameEditField = uieditfield(app.UIFigure, 'text');
            app.FoldernameEditField.Value = 'large_momentum';
            app.q_selectionDropDownLabel = uilabel(app.UIFigure);
            app.q_selectionDropDownLabel.Text = 'q_selection';
            app.q_selectionDropDown = uidropdown(app.UIFigure);
            app.q_selectionDropDown.Items = {'all'};
            app.q_selectionDropDown.ItemsData = {'1'};
            app.q_selectionDropDown.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.q_selectionDropDown.Tooltip = {'selecting one momentum to plot'};
            app.q_selectionDropDown.Value = '1';
            app.savePDFCheckBox = uicheckbox(app.UIFigure);
            app.savePDFCheckBox.Text = 'save PDF';
            app.saveplotdataCheckBox = uicheckbox(app.UIFigure);
            app.saveplotdataCheckBox.Text = 'save plot data';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.yMinEditFieldLabel = uilabel(app.UIFigure);
            app.yMinEditFieldLabel.Text = 'yMin';
            app.yMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.yMinEditField.ValueDisplayFormat = '%.2f';
            app.yMinEditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.yMaxEditFieldLabel = uilabel(app.UIFigure);
            app.yMaxEditFieldLabel.Text = 'yMax';
            app.yMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.yMaxEditField.ValueDisplayFormat = '%.2f';
            app.yMaxEditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.typeDropDownLabel = uilabel(app.UIFigure);
            app.typeDropDownLabel.Text = 'type';
            app.typeDropDown = uidropdown(app.UIFigure);
            app.typeDropDown.Items = {'imag(eps)', 'real(eps)', 'EELS', 'mixed'};
            app.typeDropDown.ItemsData = {'1', '2', '3', '4'};
            app.typeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.typeDropDown.Value = '4';
            app.pointsizeEditFieldLabel = uilabel(app.UIFigure);
            app.pointsizeEditFieldLabel.Text = 'point size';
            app.pointsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.pointsizeEditField.Limits = [0.01 Inf];
            app.pointsizeEditField.ValueDisplayFormat = '%.2f';
            app.pointsizeEditField.Value = 36;
            app.xMinEditFieldLabel = uilabel(app.UIFigure);
            app.xMinEditFieldLabel.Text = 'xMin';
            app.xMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.xMinEditField.ValueDisplayFormat = '%.2f';
            app.xMinEditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.xMaxEditFieldLabel = uilabel(app.UIFigure);
            app.xMaxEditFieldLabel.Text = 'xMax';
            app.xMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.xMaxEditField.ValueDisplayFormat = '%.2f';
            app.xMaxEditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.q_maxSliderLabel = uilabel(app.UIFigure);
            app.q_maxSliderLabel.Text = 'q_max';
            app.q_maxSlider = uislider(app.UIFigure);
            app.q_maxSlider.Limits = [0 0.5];
            app.q_maxSlider.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.q_maxSlider.Value = 0.5;
            app.holdCheckBox = uicheckbox(app.UIFigure);
            app.holdCheckBox.Text = 'hold';
            app.autoscaleButton = uibutton(app.UIFigure, 'state');
            app.autoscaleButton.Text = 'autoscale';
            app.autoscaleButton.Value = true;
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [-0.5 0 0.5];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [-0.5 0 0.5];
            app.UIAxes_2.YTickLabel = '';
            app.q_maxEditFieldLabel = uilabel(app.UIFigure);
            app.q_maxEditFieldLabel.Text = 'q_max';
            app.q_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.q_maxEditField.Limits = [0 Inf];
            app.q_maxEditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.numButton = uibutton(app.UIFigure, 'state');
            app.numButton.Text = 'num?';
            app.E_kinkeVEditFieldLabel = uilabel(app.UIFigure);
            app.E_kinkeVEditFieldLabel.Text = 'E_kin (keV)';
            app.E_kinkeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.E_kinkeVEditField.Limits = [0 Inf];
            app.E_kinkeVEditField.ValueDisplayFormat = '%.0f';
            app.E_kinkeVEditField.Value = 80;
            app.grid_xEditFieldLabel = uilabel(app.UIFigure);
            app.grid_xEditFieldLabel.Text = 'grid_x';
            app.grid_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.grid_xEditField.Limits = [0 Inf];
            app.grid_xEditField.ValueDisplayFormat = '%.0f';
            app.grid_xEditField.Value = 3;
            app.grid_yEditFieldLabel = uilabel(app.UIFigure);
            app.grid_yEditFieldLabel.Text = 'grid_y';
            app.grid_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.grid_yEditField.Limits = [0 Inf];
            app.grid_yEditField.ValueDisplayFormat = '%.0f';
            app.grid_yEditField.Value = 3;
            app.q_cutEditFieldLabel = uilabel(app.UIFigure);
            app.q_cutEditFieldLabel.Text = 'q_cut';
            app.q_cutEditField = uieditfield(app.UIFigure, 'numeric');
            app.q_cutEditField.Limits = [0 Inf];
            app.q_cutEditField.ValueDisplayFormat = '%.3e';
            app.defaultButton = uibutton(app.UIFigure, 'state');
            app.defaultButton.Text = 'default';
            app.defaultButton.Value = true;
            app.AdditionalKpointSetaroundgammaPanel = uipanel(app.UIFigure);
            app.AdditionalKpointSetaroundgammaPanel.Title = 'Additional Kpoint Set around gamma';
            app.useadditionalkptssetCheckBox = uicheckbox(app.AdditionalKpointSetaroundgammaPanel);
            app.useadditionalkptssetCheckBox.Text = 'use additional kpts set';
            app.useadditionalkptssetCheckBox.Value = true;
            app.grid_yEditField_2Label = uilabel(app.AdditionalKpointSetaroundgammaPanel);
            app.grid_yEditField_2Label.Text = 'grid_y';
            app.grid_yEditField_2 = uieditfield(app.AdditionalKpointSetaroundgammaPanel, 'numeric');
            app.grid_yEditField_2.Limits = [0 Inf];
            app.grid_yEditField_2.ValueDisplayFormat = '%.0f';
            app.grid_yEditField_2.Value = 15;
            app.grid_xEditField_2Label = uilabel(app.AdditionalKpointSetaroundgammaPanel);
            app.grid_xEditField_2Label.Text = 'grid_x';
            app.grid_xEditField_2 = uieditfield(app.AdditionalKpointSetaroundgammaPanel, 'numeric');
            app.grid_xEditField_2.Limits = [0 Inf];
            app.grid_xEditField_2.ValueDisplayFormat = '%.0f';
            app.grid_xEditField_2.Value = 15;
            app.q_limEditFieldLabel = uilabel(app.AdditionalKpointSetaroundgammaPanel);
            app.q_limEditFieldLabel.Text = 'q_lim';
            app.q_limEditField = uieditfield(app.AdditionalKpointSetaroundgammaPanel, 'numeric');
            app.q_limEditField.Limits = [0 Inf];
            app.FoldernameEditField_2Label = uilabel(app.AdditionalKpointSetaroundgammaPanel);
            app.FoldernameEditField_2Label.Text = 'Foldername';
            app.FoldernameEditField_2 = uieditfield(app.AdditionalKpointSetaroundgammaPanel, 'text');
            app.FoldernameEditField_2.Value = 'small_momentum';
            app.vacuumEditField_2Label = uilabel(app.AdditionalKpointSetaroundgammaPanel);
            app.vacuumEditField_2Label.Text = 'vacuum';
            app.vacuumEditField_2 = uieditfield(app.AdditionalKpointSetaroundgammaPanel, 'numeric');
            app.vacuumEditField_2.Limits = [0 Inf];
            app.vacuumEditField_2.ValueDisplayFormat = '%.1f';
            app.vacuumEditField_2.Value = 12.3;
            app.onlyreplaceCheckBox = uicheckbox(app.AdditionalKpointSetaroundgammaPanel);
            app.onlyreplaceCheckBox.Text = 'only replace';
            app.vacuumEditFieldLabel = uilabel(app.UIFigure);
            app.vacuumEditFieldLabel.Text = 'vacuum';
            app.vacuumEditField = uieditfield(app.UIFigure, 'numeric');
            app.vacuumEditField.Limits = [0 Inf];
            app.vacuumEditField.ValueDisplayFormat = '%.1f';
            app.vacuumEditField.Value = 12.3;
            app.qptstoolsPanel = uipanel(app.UIFigure);
            app.qptstoolsPanel.Title = 'qpts tools';
            app.createButton = uibutton(app.qptstoolsPanel, 'push');
            app.createButton.ButtonPushedFcn = createCallbackFcn(app, @createButtonPushed, true);
            app.createButton.Text = 'create';
            app.q_maxEditField_2Label = uilabel(app.qptstoolsPanel);
            app.q_maxEditField_2Label.Text = 'q_max';
            app.q_maxEditField_2 = uieditfield(app.qptstoolsPanel, 'numeric');
            app.q_maxEditField_2.Limits = [0 Inf];
            app.UIAxes_3 = uiaxes(app.qptstoolsPanel);
            title(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [-0.5 0 0.5];
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_3.YTick = [-0.5 0 0.5];
            app.UIAxes_3.YTickLabel = '';
            app.originalEditFieldLabel = uilabel(app.qptstoolsPanel);
            app.originalEditFieldLabel.Text = 'original';
            app.originalEditField = uieditfield(app.qptstoolsPanel, 'numeric');
            app.originalEditField.ValueDisplayFormat = '%.0f';
            app.originalEditField.Editable = 'off';
            app.subsetEditFieldLabel = uilabel(app.qptstoolsPanel);
            app.subsetEditFieldLabel.Text = 'subset';
            app.subsetEditField = uieditfield(app.qptstoolsPanel, 'numeric');
            app.subsetEditField.ValueDisplayFormat = '%.0f';
            app.subsetEditField.Editable = 'off';
            app.ExperimentalDataPanel = uipanel(app.UIFigure);
            app.ExperimentalDataPanel.Title = 'Experimental Data';
            app.offsetEditFieldLabel = uilabel(app.ExperimentalDataPanel);
            app.offsetEditFieldLabel.Text = 'offset';
            app.offsetEditField = uieditfield(app.ExperimentalDataPanel, 'numeric');
            app.offsetEditField.ValueDisplayFormat = '%.2f';
            app.offsetEditField.Value = 0.1;
            app.experimtaldataCheckBox = uicheckbox(app.ExperimentalDataPanel);
            app.experimtaldataCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.experimtaldataCheckBox.Text = 'experimtal data';
            app.scaleEditFieldLabel = uilabel(app.ExperimentalDataPanel);
            app.scaleEditFieldLabel.Text = 'scale';
            app.scaleEditField = uieditfield(app.ExperimentalDataPanel, 'numeric');
            app.scaleEditField.Limits = [0 Inf];
            app.scaleEditField.ValueDisplayFormat = '%.3f';
            app.scaleEditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.scaleEditField.Value = 110;
            app.shiftEditFieldLabel = uilabel(app.ExperimentalDataPanel);
            app.shiftEditFieldLabel.Text = 'shift';
            app.shiftEditField = uieditfield(app.ExperimentalDataPanel, 'numeric');
            app.shiftEditField.ValueDisplayFormat = '%.2f';
            app.shiftEditField.ValueChangedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.shiftEditField.Value = 1.3;
            app.q_minSliderLabel = uilabel(app.UIFigure);
            app.q_minSliderLabel.Text = 'q_min';
            app.q_minSlider = uislider(app.UIFigure);
            app.q_minSlider.Limits = [0 0.5];
            app.vacuum_gammaEditFieldLabel = uilabel(app.UIFigure);
            app.vacuum_gammaEditFieldLabel.Text = 'vacuum_gamma';
            app.vacuum_gammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.vacuum_gammaEditField.Limits = [0 Inf];
            app.vacuum_gammaEditField.ValueDisplayFormat = '%.1f';
            app.vacuum_gammaEditField.Value = 12.3;
            app.d_0EditFieldLabel = uilabel(app.UIFigure);
            app.d_0EditFieldLabel.Text = 'd_0';
            app.d_0EditField = uieditfield(app.UIFigure, 'numeric');
            app.d_0EditField.Limits = [0 Inf];
            app.d_0EditField.Value = 12.3;
            app.g_perpCheckBox = uicheckbox(app.UIFigure);
            app.g_perpCheckBox.Tooltip = {'eps_perp at gamma treated as -im(1/eps_perp) instead of im(eps_perp)'};
            app.g_perpCheckBox.Text = 'g_perp';
            app.g_perpCheckBox.Value = true;
            app.g_parallelCheckBox = uicheckbox(app.UIFigure);
            app.g_parallelCheckBox.Tooltip = {'eps_parallel at gamma treated as -im(1/eps_parallel) instead of im(eps_parallel)'};
            app.g_parallelCheckBox.Text = 'g_parallel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dielec_tool_d0_new

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end