classdef banddos_analysis_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        bandcharacterButtonGroup        matlab.ui.container.ButtonGroup
        sButton                         matlab.ui.control.RadioButton
        pButton                         matlab.ui.control.RadioButton
        dButton                         matlab.ui.control.RadioButton
        fButton                         matlab.ui.control.RadioButton
        allButton                       matlab.ui.control.RadioButton
        updateButton                    matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        unfoldbandstrCheckBox           matlab.ui.control.CheckBox
        atomgroupDropDownLabel          matlab.ui.control.Label
        atomgroupDropDown               matlab.ui.control.DropDown
        plotatomicstructureButton       matlab.ui.control.Button
        savePDFCheckBox                 matlab.ui.control.CheckBox
        saveplotdataCheckBox            matlab.ui.control.CheckBox
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        yMinEditFieldLabel              matlab.ui.control.Label
        yMinEditField                   matlab.ui.control.NumericEditField
        yMaxEditFieldLabel              matlab.ui.control.Label
        yMaxEditField                   matlab.ui.control.NumericEditField
        jspDropDownLabel                matlab.ui.control.Label
        jspDropDown                     matlab.ui.control.DropDown
        UnfoldingIntensitySliderLabel   matlab.ui.control.Label
        UnfoldingIntensitySlider        matlab.ui.control.Slider
        backgroundCheckBox              matlab.ui.control.CheckBox
        pointsizeEditFieldLabel         matlab.ui.control.Label
        pointsizeEditField              matlab.ui.control.NumericEditField
        writeqptsforspexButton          matlab.ui.control.Button
        gwenergiesButtonGroup           matlab.ui.container.ButtonGroup
        nonButton                       matlab.ui.control.RadioButton
        compareButton                   matlab.ui.control.RadioButton
        replaceButton                   matlab.ui.control.RadioButton
        atomsizeSliderLabel             matlab.ui.control.Label
        atomsizeSlider                  matlab.ui.control.Slider
        sizeEditFieldLabel              matlab.ui.control.Label
        sizeEditField                   matlab.ui.control.NumericEditField
        highlightCheckBox               matlab.ui.control.CheckBox
        stateEditFieldLabel             matlab.ui.control.Label
        stateEditField                  matlab.ui.control.NumericEditField
        minus1CheckBox                  matlab.ui.control.CheckBox
        plus1CheckBox                   matlab.ui.control.CheckBox
        gwoutputEditFieldLabel          matlab.ui.control.Label
        gwoutputEditField               matlab.ui.control.EditField
        datasetEditFieldLabel           matlab.ui.control.Label
        datasetEditField                matlab.ui.control.NumericEditField
        checknewestButton               matlab.ui.control.Button
        offsetEditFieldLabel            matlab.ui.control.Label
        offsetEditField                 matlab.ui.control.NumericEditField
        SOCCheckBox                     matlab.ui.control.CheckBox
        dipoleCheckBox                  matlab.ui.control.CheckBox
        FilenamecompareEditFieldLabel   matlab.ui.control.Label
        FilenamecompareEditField        matlab.ui.control.EditField
        compareCheckBox                 matlab.ui.control.CheckBox
        pointsizecompareEditFieldLabel  matlab.ui.control.Label
        pointsizecompareEditField       matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        mult_filesCheckBox              matlab.ui.control.CheckBox
        kpt_maxEditFieldLabel           matlab.ui.control.Label
        kpt_maxEditField                matlab.ui.control.NumericEditField
        on_kpt_maxCheckBox              matlab.ui.control.CheckBox
    end

    methods (Access = public)
        
    end
    

    methods (Access = private)

        % Button pushed function: updateButton
        function updateButtonPushed(app, event)
            app.UIAxes.cla; %clear graph
            close all
            if app.FilenameEditField.Value=="" %fallback when no input file is given
                app.FilenameEditField.Value='banddos.hdf';
            end
            filename_l=app.FilenameEditField.Value;
            filename=app.FilenameEditField.Value;
            %------------------checking file version and data set----------
            dataset_id=app.datasetEditField.Value;
            
            %------------------reading hdf5file values-----------------
            %a_mat =h5read(filename,strcat('/dataSet-',datase_id,'/cell/bravaisMatrix'));
            %b_mat =h5read(filename,strcat('/dataSet-',datase_id,'/cell/reciprocalCell'));
            a_mat =h5read(filename,'/cell/bravaisMatrix');
            b_mat =h5read(filename,'/cell/reciprocalCell');
            bohr_const=0.52;
            a_mat=a_mat*bohr_const;
            atoms =h5read(filename,'/atoms/positions');
            for i=1:size(atoms,2)
                atoms(1:3,i)=a_mat*atoms(1:3,i);
            end
            atoms(4,:)=h5read(filename,'/atoms/atomicNumbers');
            atoms(5,:)=h5read(filename,'/atoms/equivAtomsGroup');
            special_points_name =h5read(filename_l,'/kpts/specialPointLabels');
            %special_points_name ={'g'};
            special_points_pos =h5read(filename_l,'/kpts/specialPointIndices');
            %special_points_pos =[1];
            kpts=h5read(filename,'/kpts/coordinates');
            app.kpt_maxEditField.UpperLimitInclusive=size(kpts,2);
            if app.kpt_maxEditField.Value==0 
                app.kpt_maxEditField.Value=size(kpts,2);
            end
            %fix potential wrong numbering in hdf file
            for i=1:size(special_points_pos)
                special_points_pos(i)
                if (special_points_pos(i)> (size(kpts,2)))
                    special_points_pos(i)= size(kpts,2)
                    disp('special kpts point position fixed in hdf file')
                end
            end
            for i=1:size(kpts,2)
                kpts(1:3,i)=b_mat'*kpts(1:3,i);
            end
            kpts_dist=zeros(1,size(kpts,2));
            kpts_dist(1)=0;
            for i=2:size(kpts,2)
                kpts_dist(i)=kpts_dist(i-1)+sqrt((kpts(:,i)-kpts(:,i-1))'*(kpts(:,i)-kpts(:,i-1)));
            end
            eigenvalues=h5read(filename,'/Local/BS/eigenvalues'); %eig;kpt;jsp
            bu_switch=h5readatt(app.FilenameEditField.Value,'/general','bandUnfolding');
            if ((app.unfoldbandstrCheckBox.Value) && bu_switch==1)
                weights=h5read(filename,'/Local/BS/unfolding'); %eig;kpt;jsp
                for i=1:size(weights,1)
                    for j=1:size(weights,2)
                        for k=1:size(weights,3)
                            if weights(i,j,k)<=0.001
                                weights(i,j,k)=0.001;
                            end
                        end
                    end
                end
            else
                weights=ones(size(eigenvalues));
            end
            jspins=h5readatt(app.FilenameEditField.Value,'/general','spins');
            %l_charges=h5read(filename,'/eigenvalues/lLikeCharge'); %l_character[0,1,2,3];atoms_ntype;eig;kpt;jsp
            for i=1:max(atoms(5,:))
                l_charges(1,i,:,:,1:jspins)=h5read(filename,strcat('/Local/BS/MT:',num2str(i),'s'));
                l_charges(2,i,:,:,1:jspins)=h5read(filename,strcat('/Local/BS/MT:',num2str(i),'p'));
                l_charges(3,i,:,:,1:jspins)=h5read(filename,strcat('/Local/BS/MT:',num2str(i),'d'));
                l_charges(4,i,:,:,1:jspins)=h5read(filename,strcat('/Local/BS/MT:',num2str(i),'f'));
            end
            fermi=h5readatt(filename,'/general','lastFermiEnergy');
            eigenvalues=(eigenvalues-fermi)*27.2;
            app.stateEditField.Limits=[1,size(eigenvalues,1)];
            %--------------reading dipole information------------------
            % files muessen mit spex.hdf_000 anfangen, luecken sind erlaubt in den kpunkten, die nummerierung muss aber fortlaufend sein
            if app.dipoleCheckBox.Value
                gw_kpts=size(kpts,2);
                if app.mult_filesCheckBox.Value
                    j_total=h5readatt(strcat(app.gwoutputEditField.Value,'_','000'),'/0000/meta/system','number_of_spins');
                    tmp_dipole=h5read(strcat(app.gwoutputEditField.Value,'_','000'),strcat('/','0000','/extra/DIPOLE'));
                else
                    j_total=h5readatt(app.gwoutputEditField.Value,'/0000/meta/system','number_of_spins');
                    tmp_dipole=h5read(app.gwoutputEditField.Value,strcat('/','0000','/extra/DIPOLE'));
                end
                for j=1:j_total
                    k=0;
                    nband=size(tmp_dipole,2); %later this should be read as attribute from the hdf file
                    dipole_mat=zeros(nband,nband,gw_kpts);
                    for l=1:gw_kpts
                        if app.mult_filesCheckBox.Value
                            k_group='0000';
                            if isfile(strcat(app.gwoutputEditField.Value,'_',num2str(k,'%03i')))
                                fid=H5F.open(strcat(app.gwoutputEditField.Value,'_',num2str(k,'%03i')));
                            else
                                k=k-1;
                            end                         
                        else
                            fid=H5F.open(app.gwoutputEditField.Value);
                            k_group=num2str(k,'%04i');
                        end
                        if H5L.exists(fid,k_group,'H5P_DEFAULT')==1
                            if app.mult_filesCheckBox.Value
                                k_read=h5read(strcat(app.gwoutputEditField.Value,'_',num2str(k,'%03i')),strcat('/','0000','/meta/kpts'));
                            else
                                k_read=h5read(app.gwoutputEditField.Value,strcat('/',k_group,'/meta/kpts'));
                            end
%                             k_point=k_read(1:3);
                            if round(inv(b_mat')*kpts(1:3,l),3)==round(k_read(2:4),3)'
                                if app.mult_filesCheckBox.Value
                                    tmp_dipole=h5read(strcat(app.gwoutputEditField.Value,'_',num2str(k,'%03i')),strcat('/','0000','/extra/DIPOLE'));
                                else
                                    tmp_dipole=h5read(app.gwoutputEditField.Value,strcat('/',k_group,'/extra/DIPOLE'));
                                end
                                dipole_mat(:,:,l)=tmp_dipole(size(tmp_dipole,1),:,:);
                                k=k+1;
%                             else
%                                 dipole_mat(:,:,l)=0;
                            end
%                         else
%                             dipole_mat(:,:,l)=0;
                        end
                        if isfile(strcat(app.gwoutputEditField.Value,'_',num2str(k,'%03i')))
                            H5F.close(fid);
                        end
                    end
                end
                dipole_min=app.minEditField.Value;    %later read from hdf5
                dipole_max=app.maxEditField.Value;
            end
            %--------------readin gw energies------------------------------
            gw=0;
            if (app.replaceButton.Value) || (app.compareButton.Value)
                gw_kpts=size(kpts,2);
                if app.mult_filesCheckBox.Value
                    j_total=h5readatt(strcat(app.gwoutputEditField.Value,'_','001'),'/0000/meta/system','number_of_spins');
                    tmp_band=h5read(strcat(app.gwoutputEditField.Value,'_','001'),strcat('/0000/energies/kpts_band_index'));
                else
                    j_total=h5readatt(app.gwoutputEditField.Value,'/0000/meta/system','number_of_spins');
                    tmp_band=h5read(app.gwoutputEditField.Value,strcat('/0000/energies/kpts_band_index'));
                end
                %gw_eigenvalues=zeros(size(eigenvalues));
                gw_eigenvalues=eigenvalues;
                min_band=min(tmp_band(:,2));
                max_band=max(tmp_band(:,2));
%                gw_eigenvalues_temp=gw_eigenvalues(min_band:max_band,:,:);
                gw_eigenvalues_temp=zeros(max_band-min_band+1,gw_kpts,j_total)-50;
                for j=1:j_total
                    k=1;
                    for l=1:gw_kpts
                        if app.mult_filesCheckBox.Value
                            k_group='0000';
                            if isfile(strcat(app.gwoutputEditField.Value,'_',num2str(k,'%03i')))
                                fid=H5F.open(strcat(app.gwoutputEditField.Value,'_',num2str(k,'%03i')));
                            else
                                k_last=k;
                                for k_t=k_last:gw_kpts
                                    if isfile(strcat(app.gwoutputEditField.Value,'_',num2str(k_t,'%03i')))
                                        fid=H5F.open(strcat(app.gwoutputEditField.Value,'_',num2str(k_t,'%03i')));
                                        k=k_t;
                                        break
                                    end
                                end
                            end
                        else
                            fid=H5F.open(app.gwoutputEditField.Value);
                            k_group=num2str(k-1,'%04i');
                        end
                        if H5L.exists(fid,k_group,'H5P_DEFAULT')==1
                            if app.mult_filesCheckBox.Value
                                if H5L.exists(fid,strcat('/','0000','/meta/kpts'),'H5P_DEFAULT')==1
                                    k_read=h5read(strcat(app.gwoutputEditField.Value,'_',num2str(k,'%03i')),strcat('/','0000','/meta/kpts'));
                                else
                                    k_read=[-5,-5,-5,-5];
                                    k=k+1;
                                end
                            else
                                if H5L.exists(fid,strcat('/',k_group,'/meta/kpts'),'H5P_DEFAULT')==1
                                    k_read=h5read(app.gwoutputEditField.Value,strcat('/',k_group,'/meta/kpts'));
                                else
                                    k_read=[-5,-5,-5,-5];
                                    k=k+1;
                                end
                            end
                            if (mod(round(inv(b_mat')*kpts(1:3,l),3),1)-mod(round(k_read(2:4),3),1)')<0.002
                                if app.mult_filesCheckBox.Value
                                    tmp_energy_gw=h5read(strcat(app.gwoutputEditField.Value,'_',num2str(k,'%03i')),strcat('/','0000','/energies/GW'));
                                    tmp_energy_ks=h5read(strcat(app.gwoutputEditField.Value,'_',num2str(k,'%03i')),strcat('/','0000','/energies/KS'));
                                else
                                    tmp_energy_gw=h5read(app.gwoutputEditField.Value,strcat('/',k_group,'/energies/GW'));
                                    tmp_energy_ks=h5read(app.gwoutputEditField.Value,strcat('/',k_group,'/energies/KS'));
                                end
                                number_gw=size(tmp_energy_gw,1)/j_total;
                                tmp_energy(:,1)=tmp_energy_ks(number_gw*(j-1)+1:number_gw*j,1);
                                tmp_energy(:,2)=tmp_energy_gw(number_gw*(j-1)+1:number_gw*j,1);
%                                 tmp_energy=sortrows(tmp_energy);
                                gw_eigenvalues_temp(:,l,j)=tmp_energy(:,2);
                                k=k+1;
                            end
                        end
                        if isfile(strcat(app.gwoutputEditField.Value,'_',num2str(k,'%03i')))
                            H5F.close(fid);
                        end
                    end
                end
                fermi_gw=fermi*27.2;
                gw_eigenvalues(min_band:max_band,:,:)=(gw_eigenvalues_temp)-fermi_gw+app.offsetEditField.Value;
                %                % gw_eigenvalues=h5read(app.gwoutputEditField.Value,'/energies');
                %                %input_gw=importdata(app.gwoutputEditField.Value);
                %                fid = fopen(app.gwoutputEditField.Value,'r');
                %                data_gw_raw=textscan(fid,'%f%f%f', 'CommentStyle','#');
                %                fclose(fid);
                %                data_gw=cell2mat(data_gw_raw);
                %                %gw_data_count=0;
                %                %for i=1:size(data_gw_raw,1)
                %                %    if ((data_gw_raw(i,1)~=" ") && (data_gw_raw(i,1)~="#"))
                %                %        gw_data_count=gw_data_count+1;
                %                %        data_gw(gw_data_count,:)=data_gw_raw(i,:);
                %                %    end
                %                %end
                %                gw_nband=size(data_gw,1)/size(kpts,2);
                %                gw_kpts=size(kpts,2);
                %                gw_eigenvalues_temp=zeros(gw_nband,gw_kpts,size(eigenvalues,3));
                %                gw_eigenvalues=zeros(size(eigenvalues));
                %                for n=1:gw_nband
                %                    for k=1:gw_kpts
                %                        for j=1:size(eigenvalues,3)
                %                             if size(data_gw,2)>3
                %                                 gw_eigenvalues_temp(n,k,j)=data_gw((n-1)*gw_kpts+k,j*2);
                %                             else
                %                                 gw_eigenvalues_temp(n,k,j)=data_gw((n-1)*gw_kpts+k,j+1);
                %                             end
                %                        end
                %                    end
                %                end
                %                %fermi_gw=dlmread(strcat(app.gwoutputEditField.Value,'_fermi'));
                %                fermi_gw=fermi*27.2;
                %                gw_eigenvalues_temp=(gw_eigenvalues_temp)-fermi_gw+app.offsetEditField.Value;
                %                ind=1;
                %                if size(gw_eigenvalues_temp,1)~= size(eigenvalues,1)
                %                    [val,ind]=min(abs(eigenvalues(:,1,1)-gw_eigenvalues_temp(1,1,1)));
                %                end
                %                nbands_max=min([gw_nband,size(eigenvalues,1)-ind+1]);
                %                temp_band_upper=min([ind-1+gw_nband,size(eigenvalues,1)]);
                %                gw_eigenvalues(ind:temp_band_upper,:,:)=gw_eigenvalues_temp(1:nbands_max,:,:);
                %                mask=(gw_eigenvalues==0);
                %                weights(mask)=0.001;
                %                %gw_eigenvalues=gw_eigenvalues_temp;
                %                ks_eigenvalues=eigenvalues;
                %                eigenvalues=gw_eigenvalues;
                ks_eigenvalues=eigenvalues;
                eigenvalues=gw_eigenvalues;
                gw=1;
            end
            %--------------atomtype selection-------------------------------
            atomtype_list(1)={'all'};
            atomtype_numlist=min(atoms(5,:)):max(atoms(5,:));
            atomtype_numlist=squeeze(atomtype_numlist);
            for i=1:(size(atomtype_numlist,2))
                atomtype_list(i+1)={num2str(atomtype_numlist(i))};
            end
            atomtype_numlist=[0,atomtype_numlist];
            app.atomgroupDropDown.Items = atomtype_list;
            app.atomgroupDropDown.ItemsData = atomtype_numlist;
            atomtype=app.atomgroupDropDown.Value;          %0 means all
            %------------ jsp selection----------
            if size(eigenvalues,3)==2
                app.jspDropDown.Items = [{'both'},{'1'},{'2'}];
                app.jspDropDown.ItemsData = [0,1,2];
            else
                app.jspDropDown.Items = {'1'};
                app.jspDropDown.ItemsData = 1;
            end
            jsp=app.jspDropDown.Value; %spin
            %---------------------bandcharacter selection----------------
            if app.sButton.Value
                bandcharacter=1; %0 means all
            end
            if app.pButton.Value
                bandcharacter=2; %0 means all
            end
            if app.dButton.Value
                bandcharacter=3; %0 means all
            end
            if app.fButton.Value
                bandcharacter=4; %0 means all
            end
            if app.allButton.Value
                bandcharacter=0; %0 means all
            end
            %---------------------output name default----------------
            if app.NameEditField.Value==""
                app.NameEditField.Value='bandstructure';
            end
            %---------------------defect intensity----------------------
            def=app.UnfoldingIntensitySlider.Value;
            %---------------------setting graph properties---------------
            hold(app.UIAxes,'on')
            ylabel(app.UIAxes,'E-E_F (eV)') % y-axis label
            xlabel(app.UIAxes,'')    % x-axis label
            app.UIAxes.XTick = kpts_dist(special_points_pos);
            %app.UIAxes.GridColor = [0.15 0.15 0.15];
            %app.UIAxes.MinorGridColor = [0.1 0.1 0.1];
            app.UIAxes.XTickLabel = deblank(special_points_name);
            if app.yMinEditField.Value==0
                app.yMinEditField.Value=min(min(min(eigenvalues)));
            end
            if app.yMaxEditField.Value==0
                app.yMaxEditField.Value=max(max(max(eigenvalues)));
            end
            axis(app.UIAxes,[min(kpts_dist),max(kpts_dist),app.yMinEditField.Value,app.yMaxEditField.Value])
            %color_set=[0.4,0.7,1]; %CMY
            size_dot=app.pointsizeEditField.Value;
            %----------------------plotting-----------------------------
            if (app.compareButton.Value)
                eigenvalues=ks_eigenvalues;
                gw=0;
                plotting
                hold(app.UIAxes,'on')
                if app.savePDFCheckBox.Value
                    saving_pdf
                    hold on
                end
                saving_txt
                eigenvalues=gw_eigenvalues;
                gw=1;
                plotting
                if app.savePDFCheckBox.Value
                    saving_pdf
                    matlabfrag(app.NameEditField.Value);
                    mlf2pdf(99,app.NameEditField.Value);
                end
                saving_txt
            else
                if jsp==0
                    jsp=1;
                    plotting
                    hold(app.UIAxes,'on')
                    if app.savePDFCheckBox.Value
                        saving_pdf
                        hold on
                    end
                    saving_txt
                    jsp=2;
                    plotting
                    if app.savePDFCheckBox.Value
                        saving_pdf
                        matlabfrag(app.NameEditField.Value);
                        mlf2pdf(99,app.NameEditField.Value);
                    end
                    saving_txt
                else
                    plotting
                    if app.savePDFCheckBox.Value
                        saving_pdf
                        matlabfrag(app.NameEditField.Value);
                        mlf2pdf(99,app.NameEditField.Value);
                    end
                    saving_txt
                end
            end
            %--------plot atom groups----------
            colorbar(app.UIAxes2)
            colormap(app.UIAxes2, 'jet' )
            zlabel(app.UIAxes2,'')
            scatter3(app.UIAxes2,atoms(1,:),atoms(2,:),atoms(3,:),30*(app.atomsizeSlider.Value+0.001)*atoms(4,:),atoms(5,:),'fill')
        end

        % Button pushed function: plotatomicstructureButton
        function plotatomicstructureButtonPushed(app, event)
            filename=app.FilenameEditField.Value;
            a_mat =h5read(filename,'/cell/bravaisMatrix');
            bohr_const=0.52;
            a_mat=a_mat*bohr_const;
            atoms =h5read(filename,'/atoms/positions');
            for i=1:size(atoms,2)
                atoms(1:3,i)=a_mat*atoms(1:3,i);
            end
            atoms(4,:)=h5read(filename,'/atoms/atomicNumbers');
            atoms(5,:)=h5read(filename,'/atoms/equivAtomsGroup');
            
            for i=1:size(atoms,2)
                xyz(i,1:3)=atoms(1:3,i);
                if atoms(4,i)==14
                    labels(i) ={'Si'};
                elseif atoms(4,i)==6
                    labels(i) ={'C'};
                elseif atoms(4,i)==7
                    labels(i) ={'N'};
                elseif atoms(4,i)==42
                    labels(i) ={'Mo'};
                elseif atoms(4,i)==34
                    labels(i) ={'Se'};
                elseif atoms(4,i)==16
                    labels(i) ={'S'};
                elseif atoms(4,i)==15
                    labels(i) ={'P'};
                else
                    labels(i)={'Unknown'};
                end
            end
            figure(10)
            set(gcf,'Color','w')
            molecule3D(xyz,labels,'ballstick')
        end

        % Button pushed function: writeqptsforspexButton
        function writeqptsforspexButtonPushed(app, event)
            kpts=h5read(app.FilenameEditField.Value,'/kpts/coordinates');
            %b_mat =h5read(app.FilenameEditField.Value,'/cell/reciprocalCell');
            %             bu_switch=h5readatt(app.FilenameEditField.Value,'/general','bandUnfolding');
            %             sc_size=ones(3,1);
            %             if bu_switch==1
            %                 %sc_size=[4,4,4];
            %                 sc_size=h5read(app.FilenameEditField.Value,'/bandUnfolding/supercell');
            %             end
            for i=1:size(kpts,2)
                %kpts(1:3,i)=b_mat'*kpts(1:3,i);
                for j=1:3
                    if kpts(j,i)<1e-8
                        kpts(j,i)=0;
                    end
                    %                   kpts(j,i)=kpts(j,i)/double(sc_size(j));
                    kpts(j,i)=kpts(j,i);
                end
                kpts(4,i)=0;
            end
            dlmwrite('qpts',[size(kpts,2),1],'delimiter','\t')
            dlmwrite('qpts',kpts','-append','delimiter','\t')
        end

        % Button pushed function: checknewestButton
        function checknewestButtonPushed(app, event)
            filename=app.FilenameEditField.Value;
            latest=h5readatt(filename,'/general','lastFermiEnergy');
            app.datasetEditField.Value=latest*27.2;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'electronic band structure')
            app.bandcharacterButtonGroup = uibuttongroup(app.UIFigure);
            app.bandcharacterButtonGroup.Tooltip = {'The point size for different band characters is always comparable'};
            app.bandcharacterButtonGroup.Title = 'band character';
            app.sButton = uiradiobutton(app.bandcharacterButtonGroup);
            app.sButton.Text = 's';
            app.pButton = uiradiobutton(app.bandcharacterButtonGroup);
            app.pButton.Text = 'p';
            app.dButton = uiradiobutton(app.bandcharacterButtonGroup);
            app.dButton.Text = 'd';
            app.fButton = uiradiobutton(app.bandcharacterButtonGroup);
            app.fButton.Text = 'f';
            app.allButton = uiradiobutton(app.bandcharacterButtonGroup);
            app.allButton.Text = 'all';
            app.allButton.Value = true;
            app.updateButton = uibutton(app.UIFigure, 'push');
            app.updateButton.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.updateButton.Text = 'update';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'atom groups')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Value = 'banddos.hdf';
            app.unfoldbandstrCheckBox = uicheckbox(app.UIFigure);
            app.unfoldbandstrCheckBox.Text = 'unfold bandstr';
            app.atomgroupDropDownLabel = uilabel(app.UIFigure);
            app.atomgroupDropDownLabel.Text = 'atom group';
            app.atomgroupDropDown = uidropdown(app.UIFigure);
            app.atomgroupDropDown.Items = {'all'};
            app.atomgroupDropDown.ItemsData = {'1'};
            app.atomgroupDropDown.Tooltip = {'dot size for different atom groups is also comparable but only one representative atom from each group is selected (not summed over all atoms in the group)'};
            app.atomgroupDropDown.Value = '1';
            app.plotatomicstructureButton = uibutton(app.UIFigure, 'push');
            app.plotatomicstructureButton.ButtonPushedFcn = createCallbackFcn(app, @plotatomicstructureButtonPushed, true);
            app.plotatomicstructureButton.Text = 'plot atomic structure';
            app.savePDFCheckBox = uicheckbox(app.UIFigure);
            app.savePDFCheckBox.Text = 'save PDF';
            app.saveplotdataCheckBox = uicheckbox(app.UIFigure);
            app.saveplotdataCheckBox.Text = 'save plot data';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.yMinEditFieldLabel = uilabel(app.UIFigure);
            app.yMinEditFieldLabel.Text = 'yMin';
            app.yMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.yMinEditField.ValueDisplayFormat = '%.2f';
            app.yMaxEditFieldLabel = uilabel(app.UIFigure);
            app.yMaxEditFieldLabel.Text = 'yMax';
            app.yMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.yMaxEditField.ValueDisplayFormat = '%.2f';
            app.jspDropDownLabel = uilabel(app.UIFigure);
            app.jspDropDownLabel.Text = 'jsp';
            app.jspDropDown = uidropdown(app.UIFigure);
            app.jspDropDown.Items = {'1'};
            app.jspDropDown.Value = '1';
            app.UnfoldingIntensitySliderLabel = uilabel(app.UIFigure);
            app.UnfoldingIntensitySliderLabel.Text = 'Unfolding Intensity';
            app.UnfoldingIntensitySlider = uislider(app.UIFigure);
            app.UnfoldingIntensitySlider.Limits = [0 4];
            app.UnfoldingIntensitySlider.MajorTicks = [0 1 2 3 4];
            app.UnfoldingIntensitySlider.MajorTickLabels = {'0', '1', '2', '3', '4'};
            app.UnfoldingIntensitySlider.Tooltip = {'this defines an exponent which is multiplied with the unfolding weight ( between 0 and 4)'};
            app.UnfoldingIntensitySlider.Value = 1.5;
            app.backgroundCheckBox = uicheckbox(app.UIFigure);
            app.backgroundCheckBox.Text = 'background';
            app.pointsizeEditFieldLabel = uilabel(app.UIFigure);
            app.pointsizeEditFieldLabel.Text = 'point size';
            app.pointsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.pointsizeEditField.Limits = [0.01 Inf];
            app.pointsizeEditField.ValueDisplayFormat = '%.2f';
            app.pointsizeEditField.Value = 36;
            app.writeqptsforspexButton = uibutton(app.UIFigure, 'push');
            app.writeqptsforspexButton.ButtonPushedFcn = createCallbackFcn(app, @writeqptsforspexButtonPushed, true);
            app.writeqptsforspexButton.Tooltip = {'write qpts file for spex in internal coordinates (of supercell)'};
            app.writeqptsforspexButton.Text = 'write qpts for spex';
            app.gwenergiesButtonGroup = uibuttongroup(app.UIFigure);
            app.gwenergiesButtonGroup.Title = 'gw energies';
            app.nonButton = uiradiobutton(app.gwenergiesButtonGroup);
            app.nonButton.Text = 'non';
            app.nonButton.Value = true;
            app.compareButton = uiradiobutton(app.gwenergiesButtonGroup);
            app.compareButton.Tooltip = {'when comparing only one spin can be viewed (otherwise the plot gets to full)'};
            app.compareButton.Text = 'compare';
            app.replaceButton = uiradiobutton(app.gwenergiesButtonGroup);
            app.replaceButton.Text = 'replace';
            app.atomsizeSliderLabel = uilabel(app.UIFigure);
            app.atomsizeSliderLabel.Text = 'atom size';
            app.atomsizeSlider = uislider(app.UIFigure);
            app.atomsizeSlider.Limits = [0 2];
            app.atomsizeSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.atomsizeSlider.MajorTickLabels = {'0', '0.5', '1', '1.5', '2'};
            app.atomsizeSlider.Tooltip = {'this defines an exponent which is multiplied with the unfolding weight ( between 0 and 4)'};
            app.atomsizeSlider.Value = 1;
            app.sizeEditFieldLabel = uilabel(app.UIFigure);
            app.sizeEditFieldLabel.Text = 'size';
            app.sizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.sizeEditField.Limits = [0 Inf];
            app.sizeEditField.ValueDisplayFormat = '%.0f';
            app.sizeEditField.Value = 4;
            app.highlightCheckBox = uicheckbox(app.UIFigure);
            app.highlightCheckBox.Text = 'highlight';
            app.stateEditFieldLabel = uilabel(app.UIFigure);
            app.stateEditFieldLabel.Text = 'state';
            app.stateEditField = uieditfield(app.UIFigure, 'numeric');
            app.stateEditField.Limits = [0 Inf];
            app.stateEditField.ValueDisplayFormat = '%.0f';
            app.stateEditField.Value = 1;
            app.minus1CheckBox = uicheckbox(app.UIFigure);
            app.minus1CheckBox.Text = 'minus 1';
            app.plus1CheckBox = uicheckbox(app.UIFigure);
            app.plus1CheckBox.Text = 'plus 1';
            app.gwoutputEditFieldLabel = uilabel(app.UIFigure);
            app.gwoutputEditFieldLabel.Text = 'gw output:';
            app.gwoutputEditField = uieditfield(app.UIFigure, 'text');
            app.gwoutputEditField.Value = 'spex.hdf';
            app.datasetEditFieldLabel = uilabel(app.UIFigure);
            app.datasetEditFieldLabel.Text = 'dataset';
            app.datasetEditField = uieditfield(app.UIFigure, 'numeric');
            app.datasetEditField.Limits = [0 Inf];
            app.datasetEditField.ValueDisplayFormat = '%.2f';
            app.datasetEditField.Value = 1;
            app.checknewestButton = uibutton(app.UIFigure, 'push');
            app.checknewestButton.ButtonPushedFcn = createCallbackFcn(app, @checknewestButtonPushed, true);
            app.checknewestButton.Tooltip = {'check for the ID of the newest banddos dataset'};
            app.checknewestButton.Text = 'check newest';
            app.offsetEditFieldLabel = uilabel(app.UIFigure);
            app.offsetEditFieldLabel.Text = 'offset';
            app.offsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.offsetEditField.ValueDisplayFormat = '%.3f';
            app.SOCCheckBox = uicheckbox(app.UIFigure);
            app.SOCCheckBox.Text = 'SOC';
            app.dipoleCheckBox = uicheckbox(app.UIFigure);
            app.dipoleCheckBox.Text = 'dipole';
            app.FilenamecompareEditFieldLabel = uilabel(app.UIFigure);
            app.FilenamecompareEditFieldLabel.Text = 'Filename compare';
            app.FilenamecompareEditField = uieditfield(app.UIFigure, 'text');
            app.FilenamecompareEditField.Value = 'banddos.hdf';
            app.compareCheckBox = uicheckbox(app.UIFigure);
            app.compareCheckBox.Text = 'compare';
            app.pointsizecompareEditFieldLabel = uilabel(app.UIFigure);
            app.pointsizecompareEditFieldLabel.Text = 'point size compare';
            app.pointsizecompareEditField = uieditfield(app.UIFigure, 'numeric');
            app.pointsizecompareEditField.Limits = [0.01 Inf];
            app.pointsizecompareEditField.ValueDisplayFormat = '%.2f';
            app.pointsizecompareEditField.Value = 36;
            app.minEditFieldLabel = uilabel(app.UIFigure);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.UIFigure, 'numeric');
            app.minEditField.Limits = [0 Inf];
            app.minEditField.ValueDisplayFormat = '%.0f';
            app.minEditField.Value = 1;
            app.maxEditFieldLabel = uilabel(app.UIFigure);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxEditField.Limits = [0 Inf];
            app.maxEditField.ValueDisplayFormat = '%.0f';
            app.maxEditField.Value = 1;
            app.mult_filesCheckBox = uicheckbox(app.UIFigure);
            app.mult_filesCheckBox.Text = 'mult_files';
            app.mult_filesCheckBox.Value = true;
            app.kpt_maxEditFieldLabel = uilabel(app.UIFigure);
            app.kpt_maxEditFieldLabel.Text = 'kpt_max';
            app.kpt_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.kpt_maxEditField.Limits = [0 Inf];
            app.kpt_maxEditField.ValueDisplayFormat = '%.0f';
            app.on_kpt_maxCheckBox = uicheckbox(app.UIFigure);
            app.on_kpt_maxCheckBox.Text = 'on_kpt_max';
        end
    end

    methods (Access = public)

        % Construct app
        function app = banddos_analysis_4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end